classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainPanel                  matlab.ui.container.Panel
        Tabs                       matlab.ui.container.TabGroup
        Data                       matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        SelectdataLabel            matlab.ui.control.Label
        HealthyCheckBox            matlab.ui.control.CheckBox
        CerebralPalskyCheckBox     matlab.ui.control.CheckBox
        Graphs                     matlab.ui.container.Tab
        Label                      matlab.ui.control.Label
        WindowDropDownLabel        matlab.ui.control.Label
        WindowDropDown             matlab.ui.control.DropDown
        DataoptionsTab             matlab.ui.container.Tab
        SmoothingLabel             matlab.ui.control.Label
        AmountSliderLabel          matlab.ui.control.Label
        AmountSlider               matlab.ui.control.Slider
        Switch                     matlab.ui.control.Switch
        TabGroup                   matlab.ui.container.TabGroup
        PlotoptionsTab             matlab.ui.container.Tab
        FourieroptionsTab          matlab.ui.container.Tab
        GraphsPanel                matlab.ui.container.Panel
        UIAxesBefore               matlab.ui.control.UIAxes
        UIAxesAfter                matlab.ui.control.UIAxes
        openFilePanel              matlab.ui.container.Panel
        OpenfileButton             matlab.ui.control.Button
        CurrentfileEditFieldLabel  matlab.ui.control.Label
        CurrentfileEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)          
                msgbox('Please input an Excel file')
            else
                t = readtable(fullfile(path, file));
                app.UITable.Data = t;
            end
            fullpathname = strcat(path, file);
            app.CurrentfileEditField.Value = fullpathname;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.Tabs = uitabgroup(app.MainPanel);
            app.Data = uitab(app.Tabs);
            app.Data.Title = 'Data';
            app.UITable = uitable(app.Data);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Graphs = uitab(app.Tabs);
            app.Graphs.Title = 'Graphs';
            app.GraphsPanel = uipanel(app.Graphs);
            app.UIAxesBefore = uiaxes(app.GraphsPanel);
            title(app.UIAxesBefore, 'Original data')
            xlabel(app.UIAxesBefore, 'X')
            ylabel(app.UIAxesBefore, 'Y')
            app.UIAxesAfter = uiaxes(app.GraphsPanel);
            title(app.UIAxesAfter, 'Adjusted data')
            xlabel(app.UIAxesAfter, 'X')
            ylabel(app.UIAxesAfter, 'Y')
            app.TabGroup = uitabgroup(app.Graphs);
            app.PlotoptionsTab = uitab(app.TabGroup);
            app.PlotoptionsTab.Title = 'Plot options';
            app.SelectdataLabel = uilabel(app.PlotoptionsTab);
            app.SelectdataLabel.Text = 'Select data';
            app.HealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.HealthyCheckBox.Text = 'Healthy';
            app.CerebralPalskyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.CerebralPalskyCheckBox.Text = 'Cerebral Palsky';
            app.FourieroptionsTab = uitab(app.TabGroup);
            app.FourieroptionsTab.Title = 'Fourier options';
            app.Label = uilabel(app.FourieroptionsTab);
            app.Label.Text = '';
            app.WindowDropDownLabel = uilabel(app.FourieroptionsTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FourieroptionsTab);
            app.DataoptionsTab = uitab(app.TabGroup);
            app.DataoptionsTab.Title = 'Data options';
            app.SmoothingLabel = uilabel(app.DataoptionsTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.Switch = uiswitch(app.DataoptionsTab, 'slider');
            app.AmountSliderLabel = uilabel(app.DataoptionsTab);
            app.AmountSliderLabel.Text = 'Amount';
            app.AmountSlider = uislider(app.DataoptionsTab);
            app.AmountSlider.MajorTicks = [0 50 100];
            app.AmountSlider.MajorTickLabels = {'0', '50', '100'};
            app.AmountSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.openFilePanel = uipanel(app.MainPanel);
            app.OpenfileButton = uibutton(app.openFilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.CurrentfileEditFieldLabel = uilabel(app.openFilePanel);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.openFilePanel, 'text');
            app.CurrentfileEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end